classdef TongueTrackerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        OpenFileButton       matlab.ui.control.Button
        PlayButton           matlab.ui.control.Button
        CreateTrackerButton  matlab.ui.control.Button
        FrameCountLabel      matlab.ui.control.Label
        PauseButton          matlab.ui.control.Button
        StopButton           matlab.ui.control.Button
        ImageAxes            matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function points = createNewTrackingBox(app, frame)
            % Open figure to define region
            f1 = figure; imshow(frame);
            objectRegion =round(getPosition(imrect));
            
            objectImage_M = insertShape(frame,'Rectangle',objectRegion,'Color','red');
            close(f1); f2 = figure;
            imshow(objectImage_M);
            title('Red box shows object region');
            
            points = detectMinEigenFeatures(im2gray(frame),'ROI',objectRegion);
            
        end
        
        function points = inicializeTrackingPoints(app, frame, points)
            
            tracker = vision.PointTracker('MaxBidirectionalError',1);
            points_L = points.Location;
            
            % Initialize the tracker with the initial point locations and the initial
            initialize(tracker,points_L,frame);
            
            points = points_L;
            
        end
        
        function frameT = TrackPoints(app,oldPoints, points, rectangle, frame)
            %             [points_M, isFound] = step(tracker_M, frame);
            %             visiblePoints = points_M(isFound, :);
            %             oldInliers = oldPoints(isFound, :);
            %
            %             % Estimate the geometric transformation between the old points
            %             % and the new points and eliminate outliers
            %             [xform, inlierIdx] = estimateGeometricTransform2D(...
            %                 oldInliers, visiblePoints, 'similarity', 'MaxDistance', 4);
            %             oldInliers    = oldInliers(inlierIdx, :);
            %             visiblePoints = visiblePoints(inlierIdx, :);
            %
            %             % Display tracked points
            %             frame = insertMarker(frame, visiblePoints, '+', ...
            %                 'Color', 'white');
            frameT = frame;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            %             [ video_file_name,video_file_path ] = uigetfile({'*.avi'},'Pick a video file');      %;*.png;*.yuv;*.bmp;*.tif'},'Pick a file');
            %             if(video_file_path == 0)
            %                 return;
            %             end
            %              input_video_file = [video_file_path,video_file_name];
            videoObject =  VideoReader('subject1/proefpersoon 1.1_M.avi');
            frame = readFrame(videoObject);
            
            im = imread("noImage.png");
            imagesc(app.ImageAxes,frame);
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            global numFrames pauseS stop speed iter track points
            pauseS=true;
            stop = true;
            track = false;
            speed = 1;
            % Count the number of frames
            v = VideoReader('subject1/proefpersoon 1.1_M.avi');
            numFrames = 550;
            iter=1;
            
            
            while hasFrame(v)
                app.FrameCountLabel.Text = string(iter);
                frame = read(v,iter);
                
                %Here for pause and resume
                if pauseS == false
                    pause(0.001);
                    %Create the tracking Rectangle
                    p = createNewTrackingBox(app, frame);
                    %Initialize the Tracking
                    p = inicializeTrackingPoints(app, frame, p);
                    if p > 10
                        track = true;
                    else
                        track = false
                    end
                    while(1)
                        if pauseS == true
                            break;
                        end
                    end
                end
                if track
                    frame = TrackPoints(app, points, frame);
                end
                imagesc(app.ImageAxes,frame);
                
                iter = iter+1;
                % If frame is finished, break
                if iter>numFrames
                    break;
                end
            end
            
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            global pauseS
            if pauseS==true
                pauseS=false;
            else
                pauseS=true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpenFileButton = uibutton(app.UIFigure, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.CreateTrackerButton = uibutton(app.UIFigure, 'push');
            app.CreateTrackerButton.Text = 'Create Tracker';
            app.FrameCountLabel = uilabel(app.UIFigure);
            app.FrameCountLabel.Text = 'FrameCount';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.Text = 'Stop';
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'Video')
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = '';
            app.ImageAxes.YTick = [];
            app.ImageAxes.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TongueTrackerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end